classdef HXApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ConnectionSettingsPanel  matlab.ui.container.Panel
        BaudrateDropDown         matlab.ui.control.DropDown
        BaudrateDropDownLabel    matlab.ui.control.Label
        COMPortDropDown          matlab.ui.control.DropDown
        COMPortDropDownLabel     matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        RightPanel               matlab.ui.container.Panel
        DebugTextArea            matlab.ui.control.TextArea
        DebugTextAreaLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        iTextBox        % Line in debug textbox
        configuration   % Variables relating to current configuration
        variables       % Misc variables
        teensy          % Teensy object
        debugFile       % Debug file
    end
    
    methods (Access = private)
             
        function getSerialPortList(app)
            displayDebug(app, 'Fetching COM settings');
            serialPorts = serialportlist("available");
            list = ["Select"];
            for i=1:length(serialPorts)
                list(end+1) = serialPorts(i); %#ok<AGROW> 
            end
            app.configuration.comList       = list;
            app.configuration.baudList      = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
            app.BaudrateDropDown.Items      = app.configuration.baudList;
            app.COMPortDropDown.Items       = app.configuration.comList;
            displayDebug(app, 'Fetched COM settings');
        end
        
        function displayDebug(app, message)
            fullMessage = strjoin({datestr(now,'HH:MM:SS.FFF') ':' message});
            % Display to the dialogue box in the app
            app.iTextBox = app.iTextBox + 1;
            app.DebugTextArea.Value(app.iTextBox) = {fullMessage};
            % Save to debugFile property for final debug file
            app.debugFile.text(app.iTextBox) = {fullMessage};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            app.iTextBox = 0;
            
            % Load the last variables saved in the configuration.mat file
            displayDebug(app, 'Loading configuration.mat');
            load configuration.mat BAUD comPort plotSeconds plotUpdateTime readVarCount readVarType sendVarCount sendVarType;
            % Save the variables from configuration.mat in the  
            % configuration property
            app.configuration.comPort           = comPort;
            app.configuration.BAUD              = BAUD;
            app.configuration.readVarCount      = readVarCount;
            app.configuration.sendVarCount      = sendVarCount;
            app.configuration.readVarType       = readVarType;
            app.configuration.sendVarType       = sendVarType;
            app.configuration.plotSeconds       = plotSeconds;        %seconds
            app.configuration.plotUpdateTime    = plotUpdateTime;     %seconds
            displayDebug(app, 'Loaded configuration.mat')
            
            % Get serial port lists
            getSerialPortList(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectionSettingsPanel = uipanel(app.LeftPanel);
            app.ConnectionSettingsPanel.Title = 'Connection Settings';
            app.COMPortDropDownLabel = uilabel(app.ConnectionSettingsPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ConnectionSettingsPanel);
            app.BaudrateDropDownLabel = uilabel(app.ConnectionSettingsPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.ConnectionSettingsPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.DebugTextAreaLabel = uilabel(app.RightPanel);
            app.DebugTextAreaLabel.Text = {'Debug'; ''};
            app.DebugTextArea = uitextarea(app.RightPanel);
            app.DebugTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HXApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end